classdef Instructions < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        GobackButton       matlab.ui.control.Button
        Instruction1Label  matlab.ui.control.Label
        Instruction2Label  matlab.ui.control.Label
        Instruction3Label  matlab.ui.control.Label
        Instruction4Label  matlab.ui.control.Label
        Instruction5Label  matlab.ui.control.Label
        Image              matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        vid = videoinput('winvideo',1);
        % Create blank image
        hImage = image(app.Image,zeros(720,1280,3)); %If image resolution is 1280x720
        pause(2)
        % These lines set proper aspect ratio
        app.Image.XLim = [0,1280];
        app.Image.YLim = [0,720];
        app.Image.XTick = [];
        app.Image.YTick = [];
        pbaspect(app.Image,[1280,720,1])
        % Preview the image
        preview(vid,hImage)
            
  
        end

        % Button pushed function: GobackButton
        function GobackButtonPushed(app, event)
            closereq;
            open("HandGApp.mlapp");
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.GobackButton = uibutton(app.UIFigure, 'push');
            app.GobackButton.ButtonPushedFcn = createCallbackFcn(app, @GobackButtonPushed, true);
            app.GobackButton.Text = 'Go back';
            app.Instruction1Label = uilabel(app.UIFigure);
            app.Instruction1Label.Text = 'Instruction 1';
            app.Instruction2Label = uilabel(app.UIFigure);
            app.Instruction2Label.Text = 'Instruction 2';
            app.Instruction3Label = uilabel(app.UIFigure);
            app.Instruction3Label.Text = 'Instruction 3';
            app.Instruction4Label = uilabel(app.UIFigure);
            app.Instruction4Label.Text = 'Instruction 4';
            app.Instruction5Label = uilabel(app.UIFigure);
            app.Instruction5Label.Text = 'Instruction 5';
            app.Image = uiimage(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Instructions

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end